classdef FiberImageEnhancerA3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProcessingFileLabel           matlab.ui.control.Label
        ImageNotSavedLabel            matlab.ui.control.Label
        SlicesinthisImageLabel        matlab.ui.control.Label
        ImageSaveLamp                 matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
        Importedimagemustbe16bitandintifformatLabel  matlab.ui.control.Label
        ExportImage                   matlab.ui.control.Button
        EnhanceFiltersizeSlider       matlab.ui.control.Slider
        EnhanceFiltersizeSliderLabel  matlab.ui.control.Label
        SliceViewingSpinner           matlab.ui.control.Spinner
        SliceViewingSpinnerLabel      matlab.ui.control.Label
        ImportImage3D                 matlab.ui.control.Button
        ImportImage2D                 matlab.ui.control.Button
        UIAxesEnhanced                matlab.ui.control.UIAxes
        UIAxesOriginal                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        OrigImage % This is the image that will read into the app
        CurrentSlice % slice in the stack image
        ImageName
        ImageWindow
        RowsNum
        ColsNum
        SlicesNum
        ImageOut
        ImagePath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImage2D
        function ImportImage2DButtonPushed(app, event)

            [app.ImageName,app.ImagePath] = uigetfile('*.tif');
            cd(app.ImagePath)

            OrigImageName = app.ImageName;

            app.OrigImage = double(imread(OrigImageName));
            [app.RowsNum, app.ColsNum, app.SlicesNum] = size(app.OrigImage);

            imshow(app.OrigImage,[],'Parent',app.UIAxesOriginal,'border','tight');
            app.CurrentSlice = 1;
            app.SliceViewingSpinner.Value = 1;

            FiberThickness = uint8(app.EnhanceFiltersizeSlider.Value);
            FiberFilter = [-ones(FiberThickness) 2*ones(FiberThickness) -ones(FiberThickness)];
            ImageOutX = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter);
            ImageOutY = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter');
            ImageOutX (ImageOutX < 0) = 0;
            ImageOutY (ImageOutY < 0) = 0;
            app.ImageOut = sqrt(ImageOutX.^2 + ImageOutY.^2);

            ProcessingFileLabelChar = strcat('Processing File:',OrigImageName);

            app.ProcessingFileLabel.Text = ProcessingFileLabelChar;

            imshow(app.ImageOut,[],'Parent',app.UIAxesEnhanced,'border','tight');

            app.ImageSaveLamp.Color = [1,0,0];
            app.ImageNotSavedLabel.Text = 'Image Not Saved';

            app.SlicesinthisImageLabel.Text = 'This is a 2D image, donot change the Slice Viewing spinner';

        end

        % Button pushed function: ImportImage3D
        function ImportImage3DButtonPushed(app, event)

            [app.ImageName,app.ImagePath] = uigetfile('*.tif');
            cd(app.ImagePath)
            OrigImageName = app.ImageName;

            app.OrigImage = double(tiffreadVolume(OrigImageName));

            imshow(app.OrigImage(:,:,1),[],'Parent',app.UIAxesOriginal,'border','tight');

            [app.RowsNum, app.ColsNum, app.SlicesNum] = size(app.OrigImage);

            app.SliceViewingSpinner.Limits = [1 app.SlicesNum];
            app.SliceViewingSpinner.Value = 1;
            app.CurrentSlice = 1;

            FiberThickness = uint8(app.EnhanceFiltersizeSlider.Value);
            FiberFilter = [-ones(FiberThickness) 2*ones(FiberThickness) -ones(FiberThickness)];
            ImageOutX = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter);
            ImageOutY = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter');
            ImageOutX (ImageOutX < 0) = 0;
            ImageOutY (ImageOutY < 0) = 0;
            app.ImageOut = sqrt(ImageOutX.^2 + ImageOutY.^2);

            ProcessingFileLabelChar = strcat('Processing File:',OrigImageName);

            app.ProcessingFileLabel.Text = ProcessingFileLabelChar;

            imshow(app.ImageOut,[],'Parent',app.UIAxesEnhanced,'border','tight');

            app.ImageSaveLamp.Color = [1,0,0];
            app.ImageNotSavedLabel.Text = 'Image Not Saved';

            SliceNumChar = num2str(app.SlicesNum);
            app.SlicesinthisImageLabel.Text = strcat(SliceNumChar,' Slices in this Image');

        end

        % Value changed function: SliceViewingSpinner
        function SliceViewingSpinnerValueChanged(app, event)
            app.CurrentSlice = app.SliceViewingSpinner.Value;

            imshow(app.OrigImage(:,:,app.CurrentSlice),[],'Parent',app.UIAxesOriginal,'border','tight');

            FiberThickness = app.EnhanceFiltersizeSlider.Value;
            FiberFilter = [-ones(FiberThickness) 2*ones(FiberThickness) -ones(FiberThickness)];
            ImageOutX = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter);
            ImageOutY = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter');
            ImageOutX (ImageOutX < 0) = 0;
            ImageOutY (ImageOutY < 0) = 0;
            app.ImageOut = sqrt(ImageOutX.^2 + ImageOutY.^2);

            imshow(app.ImageOut,[],'Parent',app.UIAxesEnhanced,'border','tight');
        end

        % Value changed function: EnhanceFiltersizeSlider
        function EnhanceFiltersizeSliderValueChanged(app, event)
            if app.EnhanceFiltersizeSlider.Value <2
                app.EnhanceFiltersizeSlider.Value = 1;
            end
            if app.EnhanceFiltersizeSlider.Value >=2 && app.EnhanceFiltersizeSlider.Value < 4
                app.EnhanceFiltersizeSlider.Value = 3;
            end
            if app.EnhanceFiltersizeSlider.Value >=4 && app.EnhanceFiltersizeSlider.Value < 6
                app.EnhanceFiltersizeSlider.Value = 5;
            end
            if app.EnhanceFiltersizeSlider.Value >=6 && app.EnhanceFiltersizeSlider.Value < 8
                app.EnhanceFiltersizeSlider.Value = 7;
            end
            if app.EnhanceFiltersizeSlider.Value >=8
                app.EnhanceFiltersizeSlider.Value = 9;
            end
            FiberThickness = app.EnhanceFiltersizeSlider.Value;
            FiberFilter = [-ones(FiberThickness) 2*ones(FiberThickness) -ones(FiberThickness)];
            ImageOutX = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter);
            ImageOutY = imfilter(app.OrigImage(:,:,app.CurrentSlice), FiberFilter');
            ImageOutX (ImageOutX < 0) = 0;
            ImageOutY (ImageOutY < 0) = 0;
            app.ImageOut = sqrt(ImageOutX.^2 + ImageOutY.^2);

            imshow(app.ImageOut,[],'Parent',app.UIAxesEnhanced,'border','tight');

        end

        % Button pushed function: ExportImage
        function ExportImageButtonPushed(app, event)
            app.ImagePath = uigetdir;
            addpath(app.ImagePath)

            app.ImageSaveLamp.Color = [0.91,0.41,0.17];
            app.ImageNotSavedLabel.Text = 'Saving Image';
            pause(0.1)

            if app.SlicesNum == 1

                delimiter = '.tif';
                newStr = split(app.ImageName,delimiter);
                EnhancedImageName = strcat(app.ImagePath,'\',newStr{1},'-Enhanced',delimiter);

                ImageForSave = uint16(app.ImageOut);
                imwrite(ImageForSave,EnhancedImageName);
            end

            if app.SlicesNum > 1
                ImageForSave = zeros(app.RowsNum,app.ColsNum,app.SlicesNum);
                delimiter = '.tif';
                newStr = split(app.ImageName,delimiter);
                EnhancedImageName = strcat(newStr{1},'-Enhanced',delimiter);

                FiberThickness = app.EnhanceFiltersizeSlider.Value;
                FiberFilter = ([-ones(FiberThickness) 2*ones(FiberThickness) -ones(FiberThickness)]);

                for n = 1:app.SlicesNum
                    OrigImageSlice = app.OrigImage(:,:,n); % choose the slice for processing

                    % fiber detection using single line detector
                    ImageOutX = imfilter(OrigImageSlice, FiberFilter);
                    ImageOutY = imfilter(OrigImageSlice, FiberFilter');
                    ImageOutX (ImageOutX < 0) = 0;
                    ImageOutY (ImageOutY < 0) = 0;

                    ImageForSave(:,:,n) = sqrt(ImageOutX.^2 + ImageOutY.^2);
                end

                %                     app.OrigImage = gpuArray(app.OrigImage); % choose the slice for processing

                % fiber detection using single line detector
                %                     ImageOutX = imfilter(app.OrigImage, FiberFilter);
                %                     ImageOutY = imfilter(app.OrigImage, FiberFilter');
                %                     ImageOutX (ImageOutX < 0) = 0;
                %                     ImageOutY (ImageOutY < 0) = 0;
                %
                %                     ImageForSave = sqrt(ImageOutX.^2 + ImageOutY.^2);

                ImageOutMax = max(max(max(ImageForSave)));
                if ImageOutMax > 2^16-1
                    ImageForSave = (ImageForSave/ImageOutMax)*(2^16-1);
                end

                ImageForSave = uint16(gather(ImageForSave));

                t = Tiff(EnhancedImageName,'w');
                tagstruct.ImageLength = size(ImageForSave,1);
                tagstruct.ImageWidth = size(ImageForSave,2);
                % tagstruct.SampleFormat = 1; % uint
                tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample = 16;
                tagstruct.SamplesPerPixel = 1;
                tagstruct.Compression = Tiff.Compression.None;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software = 'MATLAB';


                for ii=1:size(ImageForSave,3)
                    setTag(t,tagstruct);
                    write(t,ImageForSave(:,:,ii));
                    writeDirectory(t);
                end
                close(t)

            end

            app.ImageSaveLamp.Color = [0,1,0];
            app.ImageNotSavedLabel.Text = 'Image Saved';

            app.ProcessingFileLabel.Text = 'Processed Image';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'Original Image')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesEnhanced = uiaxes(app.UIFigure);
            title(app.UIAxesEnhanced, 'Enhanced Image Preview')
            app.UIAxesEnhanced.XTick = [];
            app.UIAxesEnhanced.YTick = [];
            app.ImportImage2D = uibutton(app.UIFigure, 'push');
            app.ImportImage2D.ButtonPushedFcn = createCallbackFcn(app, @ImportImage2DButtonPushed, true);
            app.ImportImage2D.Text = 'Import 2D Image';
            app.ImportImage3D = uibutton(app.UIFigure, 'push');
            app.ImportImage3D.ButtonPushedFcn = createCallbackFcn(app, @ImportImage3DButtonPushed, true);
            app.ImportImage3D.Text = 'Import 3D Image';
            app.SliceViewingSpinnerLabel = uilabel(app.UIFigure);
            app.SliceViewingSpinnerLabel.Text = 'Slice Viewing';
            app.SliceViewingSpinner = uispinner(app.UIFigure);
            app.SliceViewingSpinner.Limits = [1 Inf];
            app.SliceViewingSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceViewingSpinnerValueChanged, true);
            app.SliceViewingSpinner.Value = 1;
            app.EnhanceFiltersizeSliderLabel = uilabel(app.UIFigure);
            app.EnhanceFiltersizeSliderLabel.Text = 'Enhance Filter size';
            app.EnhanceFiltersizeSlider = uislider(app.UIFigure);
            app.EnhanceFiltersizeSlider.Limits = [1 9];
            app.EnhanceFiltersizeSlider.MajorTicks = [1 3 5 7 9];
            app.EnhanceFiltersizeSlider.ValueChangedFcn = createCallbackFcn(app, @EnhanceFiltersizeSliderValueChanged, true);
            app.EnhanceFiltersizeSlider.MinorTicks = [];
            app.EnhanceFiltersizeSlider.Value = 5;
            app.ExportImage = uibutton(app.UIFigure, 'push');
            app.ExportImage.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImage.Text = 'Export Enhanced Image';
            app.Importedimagemustbe16bitandintifformatLabel = uilabel(app.UIFigure);
            app.Importedimagemustbe16bitandintifformatLabel.Text = 'Imported image must be 16 bit and in tif format';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'neurolabICON.png';
            app.ImageSaveLamp = uilamp(app.UIFigure);
            app.SlicesinthisImageLabel = uilabel(app.UIFigure);
            app.SlicesinthisImageLabel.Text = 'Slices in this Image';
            app.ImageNotSavedLabel = uilabel(app.UIFigure);
            app.ImageNotSavedLabel.Text = 'Image Not Saved';
            app.ProcessingFileLabel = uilabel(app.UIFigure);
            app.ProcessingFileLabel.Text = 'Processing File: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberImageEnhancerA3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end